classdef PHOTO_LAB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Lamp                       matlab.ui.control.Lamp
        StatusLabel                matlab.ui.control.Label
        RGBPlanesButton            matlab.ui.control.Button
        ImageEnhancementPanel      matlab.ui.container.Panel
        AdaptiveLocalNoiseReductionButton  matlab.ui.control.Button
        AdaptiveHistEQButton       matlab.ui.control.Button
        HistogramEQButton          matlab.ui.control.Button
        CompositeButton            matlab.ui.control.Button
        LaplacianButton            matlab.ui.control.Button
        DehazeButton               matlab.ui.control.Button
        AutoContrastButton         matlab.ui.control.Button
        SlidersPanel               matlab.ui.container.Panel
        Panel_7                    matlab.ui.container.Panel
        Slider_12                  matlab.ui.control.Slider
        SaturationLabel            matlab.ui.control.Label
        saveButton_3               matlab.ui.control.Button
        Panel_11                   matlab.ui.container.Panel
        Slider_13                  matlab.ui.control.Slider
        IntensityLabel             matlab.ui.control.Label
        saveButton_7               matlab.ui.control.Button
        Panel_10                   matlab.ui.container.Panel
        BlueComponentLabel         matlab.ui.control.Label
        saveButton_6               matlab.ui.control.Button
        Slider_11                  matlab.ui.control.Slider
        Panel_9                    matlab.ui.container.Panel
        GreenComponentLabel        matlab.ui.control.Label
        saveButton_5               matlab.ui.control.Button
        Slider_10                  matlab.ui.control.Slider
        Panel_8                    matlab.ui.container.Panel
        RedComponentLabel          matlab.ui.control.Label
        saveButton_4               matlab.ui.control.Button
        Slider_9                   matlab.ui.control.Slider
        Panel_6                    matlab.ui.container.Panel
        HueLabel_2                 matlab.ui.control.Label
        saveButton_2               matlab.ui.control.Button
        Slider_7                   matlab.ui.control.Slider
        Panel_5                    matlab.ui.container.Panel
        saveButton                 matlab.ui.control.Button
        BrightnessLabel            matlab.ui.control.Label
        Slider                     matlab.ui.control.Slider
        ColoringPanel              matlab.ui.container.Panel
        ConversionsPanel           matlab.ui.container.Panel
        GrayScaleButton            matlab.ui.control.Button
        InvertColorButton          matlab.ui.control.Button
        ShowAllConversionsButton   matlab.ui.control.Button
        CMYButton                  matlab.ui.control.Button
        HSVButton                  matlab.ui.control.Button
        YCbCrButton                matlab.ui.control.Button
        NTSCButton                 matlab.ui.control.Button
        SudoColoringPanel          matlab.ui.container.Panel
        DisplayTableButton         matlab.ui.control.Button
        LUTButton                  matlab.ui.control.Button
        IntensitySlicingButton     matlab.ui.control.Button
        MagnifyOutput_ImageButton  matlab.ui.control.Button
        StegnographyPanel          matlab.ui.container.Panel
        Show8BitPlanesButton       matlab.ui.control.Button
        DecodeButton               matlab.ui.control.Button
        EncodeButton               matlab.ui.control.Button
        SelectImagetohideInButton  matlab.ui.control.Button
        SelectImagetoHideButton    matlab.ui.control.Button
        HistogramPanel             matlab.ui.container.Panel
        UIAxes_4                   matlab.ui.control.UIAxes
        UIAxes_3                   matlab.ui.control.UIAxes
        RESETButton                matlab.ui.control.Button
        PHOTOLABLabel              matlab.ui.control.Label
        OutputImagePanel           matlab.ui.container.Panel
        EXPORTIMAGEButton          matlab.ui.control.Button
        HistogramButton_2          matlab.ui.control.Button
        UIAxes_2                   matlab.ui.control.UIAxes
        INputImagePanel            matlab.ui.container.Panel
        LOADIMAGEButton            matlab.ui.control.Button
        HistogramButton            matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        TransformationPanel        matlab.ui.container.Panel
        Panel_4                    matlab.ui.container.Panel
        EditField_10               matlab.ui.control.NumericEditField
        RotateButton               matlab.ui.control.Button
        RotateRightButton          matlab.ui.control.Button
        RotateLeftButton           matlab.ui.control.Button
        MirrorButton               matlab.ui.control.Button
        Panel_3                    matlab.ui.container.Panel
        EditField_2                matlab.ui.control.NumericEditField
        EditField                  matlab.ui.control.NumericEditField
        ResizeButton               matlab.ui.control.Button
        FiltersPanel               matlab.ui.container.Panel
        MedianButton               matlab.ui.control.Button
        EditField_18               matlab.ui.control.NumericEditField
        EditField_17               matlab.ui.control.NumericEditField
        EditField_16               matlab.ui.control.NumericEditField
        EditField_13               matlab.ui.control.NumericEditField
        EditField_12               matlab.ui.control.NumericEditField
        EditField_11               matlab.ui.control.NumericEditField
        AlphaTrimButton            matlab.ui.control.Button
        MinButton                  matlab.ui.control.Button
        MaxButton                  matlab.ui.control.Button
        HarmonicMeanButton         matlab.ui.control.Button
        GeometricMeanButton        matlab.ui.control.Button
        EditField_8                matlab.ui.control.NumericEditField
        MotionBlurrButton          matlab.ui.control.Button
        EditField_7                matlab.ui.control.NumericEditField
        EditField_6                matlab.ui.control.NumericEditField
        ArithematicMeanButton      matlab.ui.control.Button
        GaussianButton             matlab.ui.control.Button
        ADDNOISEPanel              matlab.ui.container.Panel
        CompareAllNoisesButton     matlab.ui.control.Button
        EditField_5                matlab.ui.control.NumericEditField
        EditField_4                matlab.ui.control.NumericEditField
        EditField_3                matlab.ui.control.NumericEditField
        GaussianNoiseButton        matlab.ui.control.Button
        SpeckleButton_2            matlab.ui.control.Button
        PoissonButton              matlab.ui.control.Button
        SaltPepperButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        input_image % Description
        output_image % Description
        grayscale
        histogram_input
        histogram_output
        salt_pepper_image
        salt_pepper_density
        speckle_image
        speckle_varience
        gaussian_image
        gaussian_mean
        poisson_image
        Average_Filter
        average_Filter_value
        Average_Image
        motion_blurr_Filter
        motion_blurr_value
        Motion_Blurr_Image
        gaussian
        invert_image
        median_image
        median_value
        row_value
        column_value
        resized_image
        fmin
        fmax
        g
        auto_contrased_image
        f2
        Id

        I_sharp2

        laplician_filter
        laplician_image
        dehazed_image


        Image_to_hide
        Image_to_hide_In
        Image_to_hide_grayscale
        Image_to_hide_In_grayscale

        Rows
        Colums
        temp

        magnified_image
        recreated

        rgb_planes % Description
        Intensity_Slicing % Description
        Property47 % Description
        brightness % Description
        Hue
        Saturation
        Intensity
        Red_Component
        Green_Component
        Blue_Component% Description
    end
    
    methods (Access = private)
        
        function results = processing(app)
            app.StatusLabel.Text = "Processing";
            app.Lamp.Color = "y";
            
        end
        
        function results = finish(app)
            app.StatusLabel.Text = "Finished";
            app.Lamp.Color = "g";
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LOADIMAGEButton
        function LOADIMAGEButtonPushed(app, event)
            RESETButtonPushed(app,event);
            [file, path] = uigetfile('*.*');
            if isequal(file, 0)
                figure(app.UIFigure);
                return;
            end
            figure(app.UIFigure);
            app.input_image = imread(fullfile(path,file));
            app.output_image = imread(fullfile(path,file));
            imshow(app.input_image,"Parent",app.UIAxes);
            imshow(app.output_image,"Parent",app.UIAxes_2);

            
            set(app.IntensitySlicingButton, 'Enable', 'on');

            set(app.AutoContrastButton, 'Enable', 'on');

            set(app.RGBPlanesButton, 'Enable', 'on');
            set(app.AdaptiveLocalNoiseReductionButton, 'Enable', 'on');
            set(app.AdaptiveHistEQButton, 'Enable', 'on');
            set(app.HistogramEQButton, 'Enable', 'on');
            set(app.CompositeButton, 'Enable', 'on');
            set(app.LaplacianButton, 'Enable', 'on');
            set(app.DehazeButton, 'Enable', 'on');


            set(app.saveButton_3, 'Enable', 'on');
            set(app.saveButton_7, 'Enable', 'on');
            set(app.saveButton_6, 'Enable', 'on');
            set(app.saveButton_5, 'Enable', 'on');
            set(app.saveButton_4, 'Enable', 'on');
            set(app.saveButton_2, 'Enable', 'on');
            set(app.saveButton, 'Enable', 'on');


            set(app.GrayScaleButton, 'Enable', 'on');
            set(app.InvertColorButton, 'Enable', 'on');
            set(app.ShowAllConversionsButton, 'Enable', 'on');
            set(app.CMYButton, 'Enable', 'on');
            set(app.HSVButton, 'Enable', 'on');
            set(app.YCbCrButton, 'Enable', 'on');
            set(app.NTSCButton, 'Enable', 'on');

            set(app.DisplayTableButton, 'Enable', 'on');
            set(app.LUTButton, 'Enable', 'on');

            set(app.MagnifyOutput_ImageButton, 'Enable', 'on');


            set(app.Show8BitPlanesButton, 'Enable', 'on');
            set(app.DecodeButton, 'Enable', 'on');
            set(app.EncodeButton, 'Enable', 'on');
            set(app.SelectImagetoHideButton, 'Enable', 'on');
            set(app.SelectImagetohideInButton, 'Enable', 'on');


            set(app.RESETButton, 'Enable', 'on');

            set(app.EXPORTIMAGEButton, 'Enable', 'on');
            set(app.HistogramButton_2, 'Enable', 'on');

%             set(app.LOADIMAGEButton, 'Enable', 'on');
            set(app.HistogramButton, 'Enable', 'on');


            set(app.RotateButton, 'Enable', 'on');
            set(app.RotateLeftButton, 'Enable', 'on');
            set(app.RotateRightButton, 'Enable', 'on');
            set(app.MirrorButton, 'Enable', 'on');
            set(app.ResizeButton, 'Enable', 'on');


            set(app.MedianButton, 'Enable', 'on');
            set(app.AlphaTrimButton, 'Enable', 'on');
            set(app.MinButton, 'Enable', 'on');
            set(app.MaxButton, 'Enable', 'on');
            set(app.GeometricMeanButton, 'Enable', 'on');
            set(app.HarmonicMeanButton, 'Enable', 'on');
            set(app.MotionBlurrButton, 'Enable', 'on');
            set(app.ArithematicMeanButton, 'Enable', 'on');
            set(app.GaussianButton, 'Enable', 'on');

            set(app.CompareAllNoisesButton, 'Enable', 'on');
            set(app.GaussianNoiseButton, 'Enable', 'on');
            set(app.SpeckleButton_2, 'Enable', 'on');
            set(app.PoissonButton, 'Enable', 'on');
            set(app.SaltPepperButton, 'Enable', 'on');

            set(app.EditField, 'Enable', 'on');
            set(app.EditField_10, 'Enable', 'on');
            set(app.EditField_11, 'Enable', 'on');
            set(app.EditField_12, 'Enable', 'on');
            set(app.EditField_13, 'Enable', 'on');
            set(app.EditField_16, 'Enable', 'on');
            set(app.EditField_17, 'Enable', 'on');
            set(app.EditField_18, 'Enable', 'on');
            set(app.EditField_2, 'Enable', 'on');
            set(app.EditField_3, 'Enable', 'on');
            set(app.EditField_4, 'Enable', 'on');
            set(app.EditField_5, 'Enable', 'on');
            set(app.EditField_6, 'Enable', 'on');
            set(app.EditField_7, 'Enable', 'on');
            set(app.EditField_8, 'Enable', 'on');
%             set(app.Ed, 'Enable', 'on');

            set(app.saveButton, 'Enable', 'on');
            set(app.saveButton_2, 'Enable', 'on');
            set(app.saveButton_3, 'Enable', 'on');
            set(app.saveButton_4, 'Enable', 'on');
            set(app.saveButton_5, 'Enable', 'on');
            set(app.saveButton_7, 'Enable', 'on');
            set(app.saveButton_6, 'Enable', 'on');


             set(app.StatusLabel, 'Enable', 'on');
            set(app.SaturationLabel, 'Enable', 'on');
            set(app.IntensityLabel, 'Enable', 'on');
            set(app.BlueComponentLabel, 'Enable', 'on');
            set(app.GreenComponentLabel, 'Enable', 'on');
            set(app.HueLabel_2, 'Enable', 'on');
            set(app.RedComponentLabel, 'Enable', 'on');
            set(app.BrightnessLabel, 'Enable', 'on');


             set(app.Slider, 'Enable', 'on');
            set(app.Slider_10, 'Enable', 'on');
            set(app.Slider_11, 'Enable', 'on');
            set(app.Slider_12, 'Enable', 'on');
            set(app.Slider_13, 'Enable', 'on');
            set(app.Slider_7, 'Enable', 'on');
            set(app.Slider_9, 'Enable', 'on');
%             set(app.Slid, 'Enable', 'on');


%             set(app.GrayScaleButton, 'Enable', 'on');
%             set(app.GrayScaleButton, 'Enable', 'on');
%             set(app.GrayScaleButton, 'Enable', 'on');
%             set(app.GrayScaleButton, 'Enable', 'on');
%             set(app.GrayScaleButton, 'Enable', 'on');
%             set(app.GrayScaleButton, 'Enable', 'on');


        end

        % Button pushed function: GrayScaleButton
        function GrayScaleButtonPushed(app, event)
            
            app.grayscale = rgb2gray(app.output_image);
            app.output_image = app.grayscale;
            imshow(app.grayscale,"parent",app.UIAxes_2);
            set(app.GrayScaleButton, 'Enable', 'off');
        end

        % Button down function: UIAxes_3
        function UIAxes_3ButtonDown(app, event)
            
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)
            app.histogram_input = imhist(app.input_image);
            plot(app.histogram_input,'Parent', app.UIAxes_3);
%             ylim auto;
        end

        % Button pushed function: HistogramButton_2
        function HistogramButton_2Pushed(app, event)
            app.histogram_output = imhist(app.output_image);
            plot(app.histogram_output,'Parent', app.UIAxes_4);
        end

        % Button pushed function: SaltPepperButton
        function SaltPepperButtonPushed(app, event)
            app.salt_pepper_density = app.EditField_3.Value;
            app.salt_pepper_image = imnoise(app.output_image,'salt & pepper',app.salt_pepper_density);
            app.output_image = app.salt_pepper_image;
            imshow(app.salt_pepper_image,"parent",app.UIAxes_2);
        end

        % Button pushed function: SpeckleButton_2
        function SpeckleButton_2Pushed(app, event)
            app.speckle_varience = app.EditField_4.Value;
            app.speckle_image = imnoise(app.output_image,"speckle",app.speckle_varience);
            app.output_image = app.speckle_image;
            imshow(app.speckle_image,"parent",app.UIAxes_2);
        end

        % Button pushed function: GaussianNoiseButton
        function GaussianNoiseButtonPushed(app, event)
            app.gaussian_mean = app.EditField_5.Value;
%             varience = app.EditField_15.Value;
            app.gaussian_image = imnoise(app.output_image,'gaussian',0.001);
            app.output_image = app.gaussian_image;
            imshow(app.gaussian_image,"parent",app.UIAxes_2);
        end

        % Button pushed function: PoissonButton
        function PoissonButtonPushed(app, event)
            app.poisson_image = imnoise(app.output_image,'poisson');
            app.output_image = app.poisson_image;
            imshow(app.poisson_image,"parent",app.UIAxes_2);   
        end

        % Button pushed function: CompareAllNoisesButton
        function CompareAllNoisesButtonPushed(app, event)
            figure
            subplot(2,2,1), imshow(app.salt_pepper_image), title('Salt and Pepper');
            subplot(2,2,2), imshow(app.speckle_image), title('Speckle');
            subplot(2,2,3), imshow(app.gaussian_image), title('Gaussian');
            subplot(2,2,4), imshow(app.poisson_image), title('Poisson');
%             
%             
%             montage({app.salt_pepper_image,app.speckle_image,app.gaussian_image,app.poisson_image});
%             title('Salt & Pepper Noise, Gaussian Noise, Speckle Noise, Poission Noise');
        end

        % Button pushed function: ArithematicMeanButton
        function ArithematicMeanButtonPushed(app, event)
            app.average_Filter_value = app.EditField_6.Value;
            app.Average_Filter = (1/(app.average_Filter_value * app.average_Filter_value)) * ones(app.average_Filter_value , app.average_Filter_value);
            app.Average_Image = imfilter(app.output_image,app.Average_Filter);

            app.output_image = app.Average_Image;
            imshow(app.Average_Image,"parent",app.UIAxes_2);  


        end

        % Button pushed function: MotionBlurrButton
        function MotionBlurrButtonPushed(app, event)
            processing(app);
            app.motion_blurr_value = app.EditField_8.Value;
            app.motion_blurr_Filter = (1/(app.motion_blurr_value)) * eye(app.motion_blurr_value);
            app.Motion_Blurr_Image = imfilter(app.output_image,app.motion_blurr_Filter);

            app.output_image = app.Motion_Blurr_Image;
            imshow(app.Motion_Blurr_Image,"parent",app.UIAxes_2);  


%             H = fspecial('motion',20,45);
%               MotionBlur = imfilter(I,H,'replicate');
%                imshow(MotionBlur);
finish(app);
        end

        % Button pushed function: GaussianButton
        function GaussianButtonPushed(app, event)
            app.gaussian = imgaussfilt(app.output_image,app.EditField_7.Value);
            app.output_image = app.gaussian;
            imshow(app.gaussian,"parent",app.UIAxes_2);  

            % fn_gau = fspecial('gaussian', 9, 2); % frquency domain filter, use sigma as, 2 is a standard deviation value a quantity expressing by how much the members of a group differ from the mean value for the group.
            % I_new3 = imfilter(I, fn_gau);
            % figure, imshow(I_new3), title('Gaussian Filter');
            % h = fspecial('gaussian',hsize,sigma) returns a rotationally symmetric Gaussian lowpass filter of size hsize with standard deviation sigma. Not recommended. Use imgaussfilt or imgaussfilt3 instead.

        end

        % Button pushed function: InvertColorButton
        function InvertColorButtonPushed(app, event)
            app.invert_image = 255 - app.output_image;
            app.output_image = app.invert_image;
            imshow(app.invert_image,"parent",app.UIAxes_2);  

        end

        % Button pushed function: MedianButton
        function MedianButtonPushed(app, event)
%           J = medfilt2(I,[m n]) performs median filtering, where each output pixel contains the median value in the m-by-n neighborhood around the corresponding pixel in the input image.
%           app.median_value = app.EditField_9.value;
            app.median_image = medfilt2(app.output_image,[5 5]);
            app.output_image = app.median_image;
            imshow(app.median_image,"parent",app.UIAxes_2);  
        end

        % Callback function
        function CompareAllBlurringOptionsButtonPushed(app, event)
            figure
            subplot(2,2,1), imshow(app.gaussian), title('Gaussian Filter');
            subplot(2,2,2), imshow(app.Average_Image), title('Averge Filter');
            subplot(2,2,3), imshow(app.Motion_Blurr_Image), title('Motion Blurr Filter');
            subplot(2,2,4), imshow(app.median_image), title('Median Filter');
%           
        end

        % Button pushed function: ResizeButton
        function ResizeButtonPushed(app, event)
            app.row_value = app.EditField_2.Value;
            app.column_value = app.EditField.Value;
            app.resized_image = imresize(app.output_image,[app.column_value,app.row_value]);
            app.output_image = app.resized_image;
            
            imshow(app.resized_image,"parent",app.UIAxes_2);  
        end

        % Button pushed function: AutoContrastButton
        function AutoContrastButtonPushed(app, event)
            processing(app);
            app.auto_contrased_image = double(app.output_image);
            app.fmin = min(min(app.auto_contrased_image));%double of min is used because we have two D matrix)
            app.fmax = max(max(app.auto_contrased_image));
            app.g = uint8(255 * (app.auto_contrased_image - app.fmin)./(app.fmax - app.fmin));% 255 is (L-1)
            app.output_image = app.g;
            imshow(app.g,"parent",app.UIAxes_2); 
        end

        % Button pushed function: CompositeButton
        function CompositeButtonPushed(app, event)
            processing(app);
            app.f2 = [-1 -1 -1; -1 9 -1; -1 -1 -1];
            app.Id = double(app.output_image);
            app.I_sharp2 = imfilter(app.Id, app.f2);
            app.output_image = uint8(app.I_sharp2);
            imshow(app.output_image,"parent",app.UIAxes_2);

            
%             myFilter8 = [-1 -1 -1; -1 9 -1; -1 -1 -1];
% 
% myOrigImage = rgb2gray(imread(‘Flower.jpg'));
% 
% figure; imshow(myOrigImage);
% 
% myFilteredImage8 = imfilter(myOrigImage,myFilter8);
% 
% figure; imshow(myFilteredImage8,[]);
        end

        % Button pushed function: LaplacianButton
        function LaplacianButtonPushed(app, event)
            processing(app);
            app.laplician_filter = [-1,-1,-1; -1,8,-1; -1,-1,-1];
            app.laplician_image = imfilter(app.output_image, app.laplician_filter);
            app.output_image = uint8(app.laplician_image);
            imshow(app.output_image,"parent",app.UIAxes_2); 
        end

        % Button pushed function: DehazeButton
        function DehazeButtonPushed(app, event)
            processing(app);
            app.dehazed_image = imreducehaze(app.output_image);
            app.output_image = uint8(app.dehazed_image);
            imshow(app.dehazed_image,"parent",app.UIAxes_2); 
        end

        % Callback function
        function CompareAutoContrastDehazeButtonPushed(app, event)
            figure
            subplot(1,2,1), imshow(app.g), title('Auto Contrast');
            subplot(1,2,2), imshow(app.dehazed_image), title('Dehaze');
            
        end

        % Button pushed function: SelectImagetoHideButton
        function SelectImagetoHideButtonPushed(app, event)
            app.Image_to_hide = app.output_image;
%             app.Image_to_hide_grayscale = rgb2gray(app.output_image);
            
        end

        % Button pushed function: SelectImagetohideInButton
        function SelectImagetohideInButtonPushed(app, event)
            app.Image_to_hide_In = app.output_image;
%             app.Image_to_hide_In_grayscale = rgb2gray(app.output_image);
        end

        % Button pushed function: Show8BitPlanesButton
        function Show8BitPlanesButtonPushed(app, event)
%             app.StatusLabel.Text = 'Processing';
            app.Lamp.Color = 'y';
            
            
            [app.Rows,app.Colums] = size(app.Image_to_hide_In);
            P1 = zeros(app.Rows,app.Colums);
            P2 = zeros(app.Rows,app.Colums);
            P3 = zeros(app.Rows,app.Colums);
            P4 = zeros(app.Rows,app.Colums);
            P5 = zeros(app.Rows,app.Colums);
            P6 = zeros(app.Rows,app.Colums);
            P7 = zeros(app.Rows,app.Colums);
            P8 = zeros(app.Rows,app.Colums);

            for i = 1:1:app.Rows
                   for j = 1:1:app.Colums
                        app.temp = de2bi(app.Image_to_hide_In(i,j),8,'left-msb');
                        P8(i,j) = app.temp(1,1);
                        P7(i,j) = app.temp(1,2);
                        P6(i,j) = app.temp(1,3);
                        P5(i,j) = app.temp(1,4);
                        P4(i,j) = app.temp(1,5);
                        P3(i,j) = app.temp(1,6);
                        P2(i,j) = app.temp(1,7);
                        P1(i,j) = app.temp(1,8);
                        app.temp = 0;
                   end
            end
            figure
            subplot(2,4,1), imshow(P1), title('Plan 1');
            subplot(2,4,2), imshow(P2), title('Plan 2');
            subplot(2,4,3), imshow(P3), title('Plan 3');
            subplot(2,4,4), imshow(P4), title('Plan 4');
            subplot(2,4,5), imshow(P5), title('Plan 5');
            subplot(2,4,6), imshow(P6), title('Plan 6');
            subplot(2,4,7), imshow(P7), title('Plan 7');
            subplot(2,4,8), imshow(P8), title('Plan 8');
%           
            app.StatusLabel.Text = 'DONE';
            app.Lamp.Color = 'g';

        end

        % Button pushed function: EncodeButton
        function EncodeButtonPushed(app, event)
%             app.StatusLabel.Text = 'Processing';
            app.Lamp.Color = 'y';
            
            
            [app.Rows,app.Colums] = size(app.Image_to_hide_In);
            P1 = zeros(app.Rows,app.Colums);
            P2 = zeros(app.Rows,app.Colums);
            P3 = zeros(app.Rows,app.Colums);
            P4 = zeros(app.Rows,app.Colums);
            P5 = zeros(app.Rows,app.Colums);
            P6 = zeros(app.Rows,app.Colums);
            P7 = zeros(app.Rows,app.Colums);
            P8 = zeros(app.Rows,app.Colums);

            for i = 1:1:app.Rows
                   for j = 1:1:app.Colums
                        app.temp = de2bi(app.Image_to_hide_In(i,j),8,'left-msb');
                        P8(i,j) = app.temp(1,1);
                        P7(i,j) = app.temp(1,2);
                        P6(i,j) = app.temp(1,3);
                        P5(i,j) = app.temp(1,4);
                        P4(i,j) = app.temp(1,5);
                        P3(i,j) = app.temp(1,6);
                        P2(i,j) = app.temp(1,7);
                        P1(i,j) = app.temp(1,8);
                        app.temp = 0;
                   end
             end
%             figure
%             subplot(2,4,1), imshow(P1), title('Plan 1');
%             subplot(2,4,2), imshow(P2), title('Plan 2');
%             subplot(2,4,3), imshow(P3), title('Plan 3');
%             subplot(2,4,4), imshow(P4), title('Plan 4');
%             subplot(2,4,5), imshow(P5), title('Plan 5');
%             subplot(2,4,6), imshow(P6), title('Plan 6');
%             subplot(2,4,7), imshow(P7), title('Plan 7');
%             subplot(2,4,8), imshow(P8), title('Plan 8');
%           
            app.StatusLabel.Text = 'DONE';
            app.Lamp.Color = 'g';


            Q1 = zeros(app.Rows,app.Colums);
            Q2 = zeros(app.Rows,app.Colums);
            Q3 = zeros(app.Rows,app.Colums);
            Q4 = zeros(app.Rows,app.Colums);
            Q5 = zeros(app.Rows,app.Colums);
            Q6 = zeros(app.Rows,app.Colums);
            Q7 = zeros(app.Rows,app.Colums);
            Q8 = zeros(app.Rows,app.Colums);

            for i = 1:1:app.Rows
                 for j = 1:1:app.Colums
                 if app.Image_to_hide(i,j) >= 128
                     app.Image_to_hide(i,j) = 255;
                 else
                     app.Image_to_hide(i,j) = 0;
                 end
                 end
            end

            for i = 1:1:app.Rows
                 for j = 1:1:app.Colums
                    app.temp = de2bi(app.Image_to_hide(i,j),8,'left-msb');
                    Q8(i,j) = app.temp(1,1);
                    Q7(i,j) = app.temp(1,2);
                    Q6(i,j) = app.temp(1,3);
                    Q5(i,j) = app.temp(1,4);
                    Q4(i,j) = app.temp(1,5);
                    Q3(i,j) = app.temp(1,6);
                    Q2(i,j) = app.temp(1,7);
                    Q1(i,j) = app.temp(1,8);
                    app.temp = 0;
                 end
            end

            app.recreated = zeros(app.Rows,app.Colums);
            for i = 1:1:app.Rows
                for j = 1:1:app.Colums
                    app.recreated(i,j) = (P8(i,j) * 128) + (P7(i,j) * 64) + (P6(i,j) * 32) + (P5(i,j) * 16)  + (P4(i,j) * 8) + (P3(i,j) * 4) + (P2(i,j) * 2)+ (Q8(i,j) * 1);
                end
            end

            app.output_image = uint8(app.recreated);
            imshow(uint8(app.recreated),"parent",app.UIAxes_2);
            app.Lamp.Color = 'g';

        end

        % Button pushed function: MagnifyOutput_ImageButton
        function MagnifyOutput_ImageButtonPushed(app, event)
            %app.magnified_image = imresize(app.output_image,[2*app.Colums,2*app.Rows]);
            figure;
            imshow(app.output_image,[]);
        end

        % Button pushed function: DecodeButton
        function DecodeButtonPushed(app, event)
            R1 = zeros(app.Rows,app.Colums);
            R2 = zeros(app.Rows,app.Colums);
            R3 = zeros(app.Rows,app.Colums);
            R4 = zeros(app.Rows,app.Colums);
            R5 = zeros(app.Rows,app.Colums);
            R6 = zeros(app.Rows,app.Colums);
            R7 = zeros(app.Rows,app.Colums);
            R8 = zeros(app.Rows,app.Colums);
            
            tec = zeros(app.Rows,app.Colums);
            
for i = 1:1:app.Rows
     for j = 1:1:app.Colums
        app.temp = de2bi(app.recreated(i,j),8,'left-msb');
        R8(i,j) = app.temp(1,1);
        R7(i,j) = app.temp(1,2);
        R6(i,j) = app.temp(1,3);
        R5(i,j) = app.temp(1,4);
        R4(i,j) = app.temp(1,5);
        R3(i,j) = app.temp(1,6);
        R2(i,j) = app.temp(1,7);
        R1(i,j) = app.temp(1,8);
        app.temp = 0;
     end
end
         for i = 1:1:app.Rows
            for j = 1:1:app.Colums
                tec(i,j) =(R1(i,j) * 128);
            end
         end


         for i = 1:1:app.Rows
                 for j = 1:1:app.Colums
                 if tec(i,j) >= 128
                     tec(i,j) = 255;
                 else
                     tec(i,j) = 0;
                 end
                 end
         end


% imshow(tec,[],"parent",app.Output2);
app.output_image = uint8(tec);
            imshow(uint8(tec),"parent",app.UIAxes_2);

        end

        % Button pushed function: EXPORTIMAGEButton
        function EXPORTIMAGEButtonPushed(app, event)
%             filter = {'*.jpg';'*.bmp';'*.jpeg';'*.png'};
%             [file, path] = uiputfile(filter);
%              [file,path] = uiputfile(app.output_image,'*.*');
%             if isequal(file, 0)
%                 figure(app.UIFigure);
%                 return;
%             end
%             figure(app.UIFigure);
            % Get the name of the file that the user wants to save.
% Note, if you're saving an image you can use imsave() instead of uiputfile().
% startingFolder = userpath;
% defaultFileName = fullfile(startingFolder, '*.*');
% [baseFileName, folder] = uiputfile(defaultFileName, 'Specify a file');
% if baseFileName == 0
%   % User clicked the Cancel button.
%   return;
% end
% fullFileName = fullfile(folder, baseFileName);
% imwrite(app.output_image, fullFileName);
% imwrite


[file path]= uiputfile('*.jpg','Save Image as');
            save=[path file]; imwrite(app.output_image,save,'jpg');
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            show = zeros(2,2);
%             app.input_image = "";
            app.output_image = app.input_image;
%             imshow(app.input_image,"Parent",app.UIAxes);
            imshow(app.output_image,"parent",app.UIAxes_2);
             app.histogram_input = imhist(show);
             plot(app.histogram_input,'Parent', app.UIAxes_3);
%              app.histogram_input = imhist(show);
             plot(app.histogram_input,'Parent', app.UIAxes_4);
             
% imshow(show,"Parent",app.UIAxes_3);
% imshow(show,"Parent",app.UIAxes_4);
% app.UIAxes_3.reset
% app.UIAxes_4.reset
            app.EditField.Value = 0;
            app.EditField_2.Value = 0;
            app.EditField_3.Value = 0;
            app.EditField_4.Value = 0;
            app.EditField_5.Value = 0;
            app.EditField_6.Value = 0;
            app.EditField_7.Value = 0;
            app.EditField_8.Value = 0;
            app.EditField_10.Value = 0;
            app.EditField_11.Value = 0;
            app.EditField_12.Value = 0;
            app.EditField_13.Value = 0;
            app.EditField_16.Value = 0;
            app.EditField_17.Value = 0;
            app.EditField_18.Value = 0;
%             app.EditField_.Value = '0';
app.StatusLabel.Text = 'Status';
            app.Lamp.Color = 'black';
            app.Slider.Value = 0;
            app.Slider_10.Value = 0;
            app.Slider_9.Value = 0;
            app.Slider_7.Value = 0;
            app.Slider_11.Value = 0;
            app.Slider_12.Value = 0;
            app.Slider_13.Value = 0;
        
        end

        % Button pushed function: RGBPlanesButton
        function RGBPlanesButtonPushed(app, event)
            app.rgb_planes = app.input_image;
            size(app.rgb_planes);
            class(app.rgb_planes);

figure, subplot(2,2,1), imshow(app.rgb_planes), title("Colored Image (RGB)");
subplot(2,2,2), imshow(app.rgb_planes(:,:,1)), title("Red Component");
subplot(2,2,3), imshow(app.rgb_planes(:,:,2)), title("Green Component");
subplot(2,2,4), imshow(app.rgb_planes(:,:,3)), title("Blue Component")
        end

        % Button pushed function: IntensitySlicingButton
        function IntensitySlicingButtonPushed(app, event)
            app.Intensity_Slicing = app.grayscale;
subplot(1,2,1), imshow(app.grayscale), title("Grey Image");

[R, C] = size(app.grayscale);

newcolor = zeros(R,C,3);

for i=1:1:R
    for j=1:1:C
        if app.grayscale(i,j) > 128
            newcolor(i,j,1) = 0.5;
        else 
            newcolor(i,j,1) = 1;
            newcolor(i,j,2) = 1;
        end
    end
end

subplot(1,2,2), imshow(newcolor), title("Intensity Slicing Image");
        end

        % Button pushed function: LUTButton
        function LUTButtonPushed(app, event)
            I = app.grayscale;

[R, C] = size(I);

%I = rgb2gray(imread("rgb_apple.jpg"));
% C =C/3 


N = zeros(R, C, 3);

for i=1:1:R
    for j=1:1:C
        if I(i,j) >= 0 && I(i,j) < 32 % Black
            N(i,j,1) = 0;
            N(i,j,2) = 0;
            N(i,j,3) = 0;
        elseif I(i,j) >= 32 && I(i,j) < 64 % Red
            N(i,j,1) = 1;
            N(i,j,2) = 0;
            N(i,j,3) = 0;
        elseif I(i,j) >= 64 && I(i,j) < 96 % Magenta
            N(i,j,1) = 1;
            N(i,j,2) = 0;
            N(i,j,3) = 1;
        elseif I(i,j) >= 96 && I(i,j) < 128 % Blue
            N(i,j,1) = 0;
            N(i,j,2) = 0;
            N(i,j,3) = 1;
        elseif I(i,j) >= 128 && I(i,j) < 160 % Cyan
            N(i,j,1) = 0;
            N(i,j,2) = 1;
            N(i,j,3) = 1;
        elseif I(i,j) >= 160 && I(i,j) < 192 % Green
            N(i,j,1) = 0;
            N(i,j,2) = 1;
            N(i,j,3) = 0;
        elseif I(i,j) >= 192 && I(i,j) < 224 % Yellow
            N(i,j,1) = 1;
            N(i,j,2) = 1;
            N(i,j,3) = 0;
        elseif I(i,j) >= 224 && I(i,j) <= 255 % White
            N(i,j,1) = 1;
            N(i,j,2) = 1;
            N(i,j,3) = 1;
        end
    end
end
    

figure;
subplot(1,2,1), imshow(I, []), title('Grey Image');
subplot(1,2,2), imshow(N, []), title('Colored Image (By Lookup Table)');
        end

        % Button pushed function: DisplayTableButton
        function DisplayTableButtonPushed(app, event)
            I = zeros(512,512);

[R, C] = size(I);

for i=1:1:R
    for j=1:1:C
        I(i,j) = round(j/2)-1;
    end
end

%I = rgb2gray(imread("rgb_apple.jpg"));
% C =C/3 


N = zeros(R, C, 3);

for i=1:1:R
    for j=1:1:C
        if I(i,j) >= 0 && I(i,j) < 32 % Black
            N(i,j,1) = 0;
            N(i,j,2) = 0;
            N(i,j,3) = 0;
        elseif I(i,j) >= 32 && I(i,j) < 64 % Red
            N(i,j,1) = 1;
            N(i,j,2) = 0;
            N(i,j,3) = 0;
        elseif I(i,j) >= 64 && I(i,j) < 96 % Magenta
            N(i,j,1) = 0;
            N(i,j,2) = 1;
            N(i,j,3) = 1;
        elseif I(i,j) >= 96 && I(i,j) < 128 % Blue
            N(i,j,1) = 0;
            N(i,j,2) = 0;
            N(i,j,3) = 1;
        elseif I(i,j) >= 128 && I(i,j) < 160 % Cyan
            N(i,j,1) = 1;
            N(i,j,2) = 0;
            N(i,j,3) = 1;
        elseif I(i,j) >= 160 && I(i,j) < 192 % Green
            N(i,j,1) = 0;
            N(i,j,2) = 1;
            N(i,j,3) = 0;
        elseif I(i,j) >= 192 && I(i,j) < 224 % Yellow
            N(i,j,1) = 1;
            N(i,j,2) = 1;
            N(i,j,3) = 0;
        elseif I(i,j) >= 224 && I(i,j) <= 255 % White
            N(i,j,1) = 1;
            N(i,j,2) = 1;
            N(i,j,3) = 1;
        end
    end
end
    

figure;
subplot(1,2,1), imshow(I, []), title('Grey Image');
subplot(1,2,2), imshow(N, []), title('Colored Image (By Lookup Table)');
        end

        % Button pushed function: HSVButton
        function HSVButtonPushed(app, event)
            Hsv_img = rgb2hsv(app.output_image);
figure; imshow(Hsv_img), title("HSV Image");
        end

        % Button pushed function: NTSCButton
        function NTSCButtonPushed(app, event)
            ntsc_img = rgb2ntsc(app.output_image);
figure; imshow(ntsc_img), title("NTSC Image");
        end

        % Button pushed function: YCbCrButton
        function YCbCrButtonPushed(app, event)
            YCbCr_img = rgb2ycbcr(app.output_image);
 figure; imshow(YCbCr_img), title("YCbCr Image");
        end

        % Button pushed function: CMYButton
        function CMYButtonPushed(app, event)
            A = app.output_image;

% B = A;

B = 255 - A;

% figure, subplot(1,2,1), imshow(A), title("Colored Image");
figure; imshow(B, []), title("CMY Image");
        end

        % Button pushed function: ShowAllConversionsButton
        function ShowAllConversionsButtonPushed(app, event)
                   colorimg = app.output_image;
figure, subplot(3,3,1), imshow(colorimg), title("Colored Image");
            
            gray = rgb2gray(app.output_image);
       subplot(3,3,2), imshow(gray), title("GrayScale Image");


                A = app.output_image;

% B = A;

B = 255 - A;

% figure, subplot(1,2,1), imshow(A), title("Colored Image");
subplot(3,3,3), imshow(B), title("InvertColor Image");
            
%             colorimg = app.output_image;
% figure, subplot(3,3,4), imshow(colorimg), title("Colored Image");

subplot(3,3,4), imshow(B), title("CMY Image");

ntsc_img = rgb2ntsc(colorimg);
subplot(3,3,5), imshow(ntsc_img), title("NTSC Image");


YCbCr_img = rgb2ycbcr(colorimg);
 subplot(3,3,6), imshow(YCbCr_img), title("YCbCr Image");

Hsv_img = rgb2hsv(colorimg);
subplot(3,3,7), imshow(Hsv_img), title("HSV Image");
        end

        % Button pushed function: RotateRightButton
        function RotateRightButtonPushed(app, event)
            I = app.output_image;
            J = imrotate(I,90);
            imshow(J,"Parent",app.UIAxes_2);
            app.output_image = J;

        end

        % Button pushed function: RotateLeftButton
        function RotateLeftButtonPushed(app, event)
               I = app.output_image;
            J = imrotate(I,-90);
            imshow(J,"Parent",app.UIAxes_2);
            app.output_image = J;

        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            I = app.output_image;
            L = imrotate(I,app.EditField_10.Value,'bilinear', 'crop');
            imshow(L,"Parent",app.UIAxes_2);
            app.output_image = L;
        end

        % Value changed function: EditField_10
        function EditField_10ValueChanged(app, event)
            value = app.EditField_10.Value;
            
        end

        % Button pushed function: MirrorButton
        function MirrorButtonPushed(app, event)
            I = app.output_image;
I2 = flip(I ,2);           %# horizontal flip
I3 = flip(I ,1);           %# vertical flip
I4 = flip(I3,2);    %# horizontal+vertical flip
% subplot(2,2,1), imshow(I)
% subplot(2,2,2), imshow(I2)
% subplot(2,2,3), imshow(I3)
% subplot(2,2,4), imshow(I4)
I5 = imrotate(I4,180);
I4 = flip(uint8(I5), 2);
imshow(I4,"Parent",app.UIAxes_2);
            app.output_image = I4;
        end

        % Callback function
        function BrightnessButtonPushed(app, event)
%             value_by_slider = app.Slider.Value;
%            A = app.output_image;
% 
% % B = A;
% 
% B = A + value_by_slider;
% 
% imshow(B,"Parent",app.UIAxes_2);
%             app.output_image = B;


        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            
            changingValue = event.Value;
                 value_by_slider = changingValue;
            A = app.output_image;
% 
% % B = A;
% 
 app.brightness = A + value_by_slider;
% 
 imshow(app.brightness,"Parent",app.UIAxes_2);
%              app.output_image = B;
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            app.output_image = app.brightness;
        end

        % Value changing function: Slider_7
        function Slider_7ValueChanging(app, event)
            changingValue = event.Value;
            A = app.output_image;

% A1 = A(:,:,1);
% A2 = A(:,:,2);
% A3 = A(:,:,3);

% [R, C] = size(A);
% C = C/3;

Hsv_img = rgb2hsv(A);
New_hsv_img = Hsv_img;


H = Hsv_img(:,:,1);


m = changingValue;

New_hsv_img(:,:,1) = Hsv_img(:,:,1) + m;

app.Hue = hsv2rgb(New_hsv_img);

imshow(app.Hue,"Parent",app.UIAxes_2);
        end

        % Button pushed function: saveButton_2
        function saveButton_2Pushed(app, event)
            app.output_image = app.Hue;
        end

        % Value changing function: Slider_12
        function Slider_12ValueChanging(app, event)
            changingValue = event.Value;
             A = app.output_image;

% A1 = A(:,:,1);
% A2 = A(:,:,2);
% A3 = A(:,:,3);

% [R, C] = size(A);
% C = C/3;

Hsv_img = rgb2hsv(A);
New_hsv_img = Hsv_img;


S = Hsv_img(:,:,2);


m = changingValue;

New_hsv_img(:,:,2) = Hsv_img(:,:,2) + m;

app.Saturation = hsv2rgb(New_hsv_img);

imshow(app.Saturation,"Parent",app.UIAxes_2);
        end

        % Button pushed function: saveButton_3
        function saveButton_3Pushed(app, event)
            app.output_image = app.Saturation;
        end

        % Value changing function: Slider_13
        function Slider_13ValueChanging(app, event)
            changingValue = event.Value;
            A = app.output_image;

% A1 = A(:,:,1);
% A2 = A(:,:,2);
% A3 = A(:,:,3);

% [R, C] = size(A);
% C = C/3;

Hsv_img = rgb2hsv(A);
New_hsv_img = Hsv_img;


I = Hsv_img(:,:,3);


m = changingValue;

New_hsv_img(:,:,3) = Hsv_img(:,:,3) + m;

app.Intensity = hsv2rgb(New_hsv_img);

imshow(app.Intensity,"Parent",app.UIAxes_2);
        end

        % Button pushed function: saveButton_7
        function saveButton_7Pushed(app, event)
            app.output_image = app.Intensity;
        end

        % Value changing function: Slider_9
        function Slider_9ValueChanging(app, event)
            changingValue = event.Value;
            A = app.output_image;

app.Red_Component = A;


m = changingValue;

app.Red_Component(:,:,1) = A(:,:,1) + m;


imshow(app.Red_Component,"Parent",app.UIAxes_2);


        end

        % Value changing function: Slider_10
        function Slider_10ValueChanging(app, event)
            changingValue = event.Value;
            A = app.output_image;


app.Green_Component = A;


m = changingValue;


app.Green_Component(:,:,2) = A(:,:,2) + m;


imshow(app.Green_Component,"Parent",app.UIAxes_2);


        end

        % Value changing function: Slider_11
        function Slider_11ValueChanging(app, event)
            changingValue = event.Value;
            A = app.output_image;


app.Blue_Component = A;

m = changingValue;


app.Blue_Component(:,:,3) = A(:,:,3) + m;

imshow(app.Blue_Component,"Parent",app.UIAxes_2);

        end

        % Button pushed function: saveButton_4
        function saveButton_4Pushed(app, event)
            app.output_image = app.Red_Component; 
        end

        % Button pushed function: saveButton_5
        function saveButton_5Pushed(app, event)
             app.output_image = app.Green_Component;
        end

        % Button pushed function: saveButton_6
        function saveButton_6Pushed(app, event)
             app.output_image = app.Blue_Component;
        end

        % Button pushed function: AdaptiveLocalNoiseReductionButton
        function AdaptiveLocalNoiseReductionButtonPushed(app, event)
processing(app);
            % A = imread('saturn.png');
B = app.grayscale;
sz = size(B,1)*size(B,2);


%Add gaussian noise with mean 0 and variance 0.005
B = app.output_image;
% figure,imshow(B); title('Image with gaussian noise');
B = double(B);

%Define the window size mxn
M = 5;
N = 5;

%Pad the matrix with zeros on all sides
C = padarray(B,[floor(M/2),floor(N/2)]);


lvar = zeros([size(B,1) size(B,2)]);
lmean = zeros([size(B,1) size(B,2)]);
temp = zeros([size(B,1) size(B,2)]);
NewImg = zeros([size(B,1) size(B,2)]);

for i = 1:size(C,1)-(M-1)
    for j = 1:size(C,2)-(N-1)
        
        
        temp = C(i:i+(M-1),j:j+(N-1));
        tmp =  temp(:);
             %Find the local mean and local variance for the local region        
        lmean(i,j) = mean(tmp);
        lvar(i,j) = mean(tmp.^2)-mean(tmp).^2;
        
    end
end

%Noise variance and average of the local variance
nvar = sum(lvar(:))/sz;

%If noise_variance > local_variance then local_variance=noise_variance
 lvar = max(lvar,nvar);     

 %Final_Image = B- (noise variance/local variance)*(B-local_mean);
 NewImg = nvar./lvar;
 NewImg = NewImg.*(B-lmean);
 NewImg = B-NewImg;

 %Convert the image to uint8 format.
  NewImg = uint8(NewImg);
% figure,imshow(NewImg);title('Restored Image using Adaptive Local filter');
imshow(NewImg,"Parent",app.UIAxes_2);
 app.output_image = NewImg; 
% app.Lamp.Color = 'g';
        end

        % Button pushed function: HistogramEQButton
        function HistogramEQButtonPushed(app, event)
            processing(app);
            I = app.output_image;
I = im2double(I);
J = histeq(I);
imshow(J,"Parent",app.UIAxes_2);
 app.output_image = J; 
        end

        % Button pushed function: AdaptiveHistEQButton
        function AdaptiveHistEQButtonPushed(app, event)
            processing(app);
            I = app.output_image;
I = im2double(I);
J = adapthisteq(I);
imshow(J,"Parent",app.UIAxes_2);
 app.output_image = J; 
        end

        % Button pushed function: MinButton
        function MinButtonPushed(app, event)
            value = app.EditField_16.Value;
            
            A = app.output_image;
            B = ordfilt2(A,1,ones(value,value));
            imshow(B,"Parent",app.UIAxes_2);
             app.output_image = B;

        end

        % Value changed function: EditField_16
        function EditField_16ValueChanged(app, event)
            value = app.EditField_16.Value;
            
        end

        % Button pushed function: MaxButton
        function MaxButtonPushed(app, event)
            value = app.EditField_17.Value;
            
            A = app.output_image;
            B = ordfilt2(A,value*value,ones(value,value));
            imshow(B,"Parent",app.UIAxes_2);
            app.output_image = B;
        end

        % Value changed function: EditField_17
        function EditField_17ValueChanged(app, event)
            value = app.EditField_17.Value;
            
        end

        % Button pushed function: HarmonicMeanButton
        function HarmonicMeanButtonPushed(app, event)
processing(app);
            % myImg = app.output_image;
% figure, imshow(myImg, []), title("Original Image");

% Conert it into Grey_Scale Image
% myGrayImg = app.grayscale;
% figure, imshow(myGrayImg, []), title("Gray Scale Image");    
% myGrayImg = double(myGrayImg);
N = app.output_image;
% figure, imshow(N, []), title("Noisy Image");    


% To compute mean and variance
N = double(N);

%---------------------------------------------------------------------------------------------------------

value = app.EditField_13.Value;
[R, C] = size(N);
B = zeros(R, C);
M = value;
Start = round(M/2);   % S = 2
End = Start - 1;        % E = 1
for i=Start : 1 : R-End
    for j=Start : 1 : C-End
        Sxy = N(i-End:i+End,j-End:j+End);
        X = reshape(Sxy, (1), []);
        X = 1./X;
        B(i,j) = M*M./sum(X);
    end
end

% figure, imshow(B, []), title("Harmonic Image");
imshow(B,"Parent",app.UIAxes_2);
app.output_image = B;
finish(app);
        end

        % Value changed function: EditField_13
        function EditField_13ValueChanged(app, event)
            value = app.EditField_13.Value;
            
        end

        % Button pushed function: GeometricMeanButton
        function GeometricMeanButtonPushed(app, event)
            processing(app);
            % Getting Original Image on which we want to do work:
%  clear;
% originalImage = app.output_image;
 % Showing oringinal image:
% figure; imshow(originalImage), title("Original Image");          %Figure 1

 % Converting orignal image into Gray Image:
grayImage = app.output_image;
 % Showing Gray image:
% figure; imshow(grayImage), title("Gray Image");                  %Figure 2

[R, C] = size(grayImage);
OutputImage = zeros(R, C);

 value = app.EditField_12.Value;
m = value;

for i=2:1:R-1
    for j=2:1:C-1
        localPatch = grayImage(i-1:i+1, j-1:j+1);
         modifyShape = reshape(localPatch, (1), []);
         
         prodC = prod(modifyShape, 2);
        geometricFilter = (prodC)^(1/(m*m));
        OutputImage(i, j) = geometricFilter;
    end
end
%  figure; imshow(OutputImage, []), title("Geometric Image");     %Figure 4
imshow(OutputImage,"Parent",app.UIAxes_2);
app.output_image = OutputImage;
finish(app);
        end

        % Value changed function: EditField_12
        function EditField_12ValueChanged(app, event)
            value = app.EditField_12.Value;
            
        end

        % Callback function
        function UniformButtonPushed(app, event)
%             A = app.output_image;
% B = app.grayscale;
%             s0 = size(B);
%             value = app.EditField_14.Value;
% var = value; % pick a noise variance that's reasonable
% Unoise = impict  + sqrt(var)*randn(s0);
% imshow(Unoise,"Parent",app.UIAxes_2);
% app.output_image = Unoise;
        end

        % Button pushed function: AlphaTrimButton
        function AlphaTrimButtonPushed(app, event)
            processing(app);
            myImg = app.output_image;

% Conert it into Grey_Scale Image
%myGrayImg = rgb2gray(myImg);
%figure, imshow(myGrayImg, []), title("Gray Scale Image");    

%N = imnoise(myGrayImg, 'salt & pepper', 0.03);
%figure, imshow(N, []), title("Noisy Image");    

% To compute mean and variance
N = double(myImg);

%---------------------------------------------------------------------------------------------------------
a = app.EditField_18.Value;
M = app.EditField_11.Value;


d = a/2;
d = double(d);

[R, C] = size(myImg);
B = zeros(R, C);

Start = round(M/2);   % S = 2
End = Start - 1;        % E = 1


for i=Start : 1 : R-End
    for j=Start : 1 : C-End
        Sxy = N(i-End:i+End,j-End:j+End);
        X = reshape(Sxy, (1), []); 
        X = sort(X);
        if d == 0 
            B(i,j) = sum(X)/length(X);
        elseif d == (((M*M) -1)/2)
            B(i,j) = medfilt2(X);
        else        %if d > 0 && d < (a/2)
            X_trim = X(d+1 :  (length(X)-d));
            X_avg = sum(X_trim)/length(X_trim);
            B(i,j) = X_avg;
        end
    end
end

%figure, imshow(B, []), title("Alpha Trimmed Mean Filtered Image");
imshow(B,"Parent",app.UIAxes_2);
app.output_image = B;
finish(app);
        end

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            value = app.EditField_11.Value;
            
        end

        % Value changed function: EditField_18
        function EditField_18ValueChanged(app, event)
            value = app.EditField_18.Value;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ADDNOISEPanel = uipanel(app.UIFigure);
            app.ADDNOISEPanel.Title = 'ADD NOISE';
            app.SaltPepperButton = uibutton(app.ADDNOISEPanel, 'push');
            app.SaltPepperButton.ButtonPushedFcn = createCallbackFcn(app, @SaltPepperButtonPushed, true);
            app.SaltPepperButton.Enable = 'off';
            app.SaltPepperButton.Text = 'Salt & Pepper';
            app.PoissonButton = uibutton(app.ADDNOISEPanel, 'push');
            app.PoissonButton.ButtonPushedFcn = createCallbackFcn(app, @PoissonButtonPushed, true);
            app.PoissonButton.Enable = 'off';
            app.PoissonButton.Text = 'Poisson';
            app.SpeckleButton_2 = uibutton(app.ADDNOISEPanel, 'push');
            app.SpeckleButton_2.ButtonPushedFcn = createCallbackFcn(app, @SpeckleButton_2Pushed, true);
            app.SpeckleButton_2.Enable = 'off';
            app.SpeckleButton_2.Text = 'Speckle';
            app.GaussianNoiseButton = uibutton(app.ADDNOISEPanel, 'push');
            app.GaussianNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianNoiseButtonPushed, true);
            app.GaussianNoiseButton.Enable = 'off';
            app.GaussianNoiseButton.Text = 'Gaussian Noise';
            app.EditField_3 = uieditfield(app.ADDNOISEPanel, 'numeric');
            app.EditField_3.Enable = 'off';
            app.EditField_3.Tooltip = {''};
            app.EditField_4 = uieditfield(app.ADDNOISEPanel, 'numeric');
            app.EditField_4.Enable = 'off';
            app.EditField_4.Tooltip = {''};
            app.EditField_5 = uieditfield(app.ADDNOISEPanel, 'numeric');
            app.EditField_5.Enable = 'off';
            app.EditField_5.Tooltip = {''};
            app.CompareAllNoisesButton = uibutton(app.ADDNOISEPanel, 'push');
            app.CompareAllNoisesButton.ButtonPushedFcn = createCallbackFcn(app, @CompareAllNoisesButtonPushed, true);
            app.CompareAllNoisesButton.Enable = 'off';
            app.CompareAllNoisesButton.Text = 'Compare All Noises';
            app.FiltersPanel = uipanel(app.UIFigure);
            app.FiltersPanel.Title = 'Filters';
            app.GaussianButton = uibutton(app.FiltersPanel, 'push');
            app.GaussianButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianButtonPushed, true);
            app.GaussianButton.Enable = 'off';
            app.GaussianButton.Text = 'Gaussian ';
            app.ArithematicMeanButton = uibutton(app.FiltersPanel, 'push');
            app.ArithematicMeanButton.ButtonPushedFcn = createCallbackFcn(app, @ArithematicMeanButtonPushed, true);
            app.ArithematicMeanButton.Enable = 'off';
            app.ArithematicMeanButton.Text = 'Arithematic Mean';
            app.EditField_6 = uieditfield(app.FiltersPanel, 'numeric');
            app.EditField_6.Enable = 'off';
            app.EditField_6.Tooltip = {''};
            app.EditField_7 = uieditfield(app.FiltersPanel, 'numeric');
            app.EditField_7.Enable = 'off';
            app.EditField_7.Tooltip = {''};
            app.MotionBlurrButton = uibutton(app.FiltersPanel, 'push');
            app.MotionBlurrButton.ButtonPushedFcn = createCallbackFcn(app, @MotionBlurrButtonPushed, true);
            app.MotionBlurrButton.Enable = 'off';
            app.MotionBlurrButton.Text = 'Motion Blurr';
            app.EditField_8 = uieditfield(app.FiltersPanel, 'numeric');
            app.EditField_8.Enable = 'off';
            app.EditField_8.Tooltip = {''};
            app.GeometricMeanButton = uibutton(app.FiltersPanel, 'push');
            app.GeometricMeanButton.ButtonPushedFcn = createCallbackFcn(app, @GeometricMeanButtonPushed, true);
            app.GeometricMeanButton.Enable = 'off';
            app.GeometricMeanButton.Text = 'Geometric Mean';
            app.HarmonicMeanButton = uibutton(app.FiltersPanel, 'push');
            app.HarmonicMeanButton.ButtonPushedFcn = createCallbackFcn(app, @HarmonicMeanButtonPushed, true);
            app.HarmonicMeanButton.Enable = 'off';
            app.HarmonicMeanButton.Text = 'Harmonic Mean';
            app.MaxButton = uibutton(app.FiltersPanel, 'push');
            app.MaxButton.ButtonPushedFcn = createCallbackFcn(app, @MaxButtonPushed, true);
            app.MaxButton.Enable = 'off';
            app.MaxButton.Text = 'Max';
            app.MinButton = uibutton(app.FiltersPanel, 'push');
            app.MinButton.ButtonPushedFcn = createCallbackFcn(app, @MinButtonPushed, true);
            app.MinButton.Enable = 'off';
            app.MinButton.Text = 'Min';
            app.AlphaTrimButton = uibutton(app.FiltersPanel, 'push');
            app.AlphaTrimButton.ButtonPushedFcn = createCallbackFcn(app, @AlphaTrimButtonPushed, true);
            app.AlphaTrimButton.Enable = 'off';
            app.AlphaTrimButton.Text = 'Alpha Trim';
            app.EditField_11 = uieditfield(app.FiltersPanel, 'numeric');
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.EditField_11.Enable = 'off';
            app.EditField_11.Tooltip = {''};
            app.EditField_12 = uieditfield(app.FiltersPanel, 'numeric');
            app.EditField_12.ValueChangedFcn = createCallbackFcn(app, @EditField_12ValueChanged, true);
            app.EditField_12.Enable = 'off';
            app.EditField_12.Tooltip = {''};
            app.EditField_13 = uieditfield(app.FiltersPanel, 'numeric');
            app.EditField_13.ValueChangedFcn = createCallbackFcn(app, @EditField_13ValueChanged, true);
            app.EditField_13.Enable = 'off';
            app.EditField_13.Tooltip = {''};
            app.EditField_16 = uieditfield(app.FiltersPanel, 'numeric');
            app.EditField_16.ValueChangedFcn = createCallbackFcn(app, @EditField_16ValueChanged, true);
            app.EditField_16.Enable = 'off';
            app.EditField_16.Tooltip = {''};
            app.EditField_17 = uieditfield(app.FiltersPanel, 'numeric');
            app.EditField_17.ValueChangedFcn = createCallbackFcn(app, @EditField_17ValueChanged, true);
            app.EditField_17.Enable = 'off';
            app.EditField_17.Tooltip = {''};
            app.EditField_18 = uieditfield(app.FiltersPanel, 'numeric');
            app.EditField_18.ValueChangedFcn = createCallbackFcn(app, @EditField_18ValueChanged, true);
            app.EditField_18.Enable = 'off';
            app.EditField_18.Tooltip = {''};
            app.MedianButton = uibutton(app.FiltersPanel, 'push');
            app.MedianButton.ButtonPushedFcn = createCallbackFcn(app, @MedianButtonPushed, true);
            app.MedianButton.Enable = 'off';
            app.MedianButton.Text = ' Median';
            app.TransformationPanel = uipanel(app.UIFigure);
            app.TransformationPanel.Title = 'Transformation';
            app.Panel_3 = uipanel(app.TransformationPanel);
            app.ResizeButton = uibutton(app.Panel_3, 'push');
            app.ResizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeButtonPushed, true);
            app.ResizeButton.Enable = 'off';
            app.ResizeButton.Text = 'Resize';
            app.EditField = uieditfield(app.Panel_3, 'numeric');
            app.EditField.Enable = 'off';
            app.EditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Enable = 'off';
            app.MirrorButton = uibutton(app.TransformationPanel, 'push');
            app.MirrorButton.ButtonPushedFcn = createCallbackFcn(app, @MirrorButtonPushed, true);
            app.MirrorButton.Enable = 'off';
            app.MirrorButton.Text = 'Mirror';
            app.RotateLeftButton = uibutton(app.TransformationPanel, 'push');
            app.RotateLeftButton.ButtonPushedFcn = createCallbackFcn(app, @RotateLeftButtonPushed, true);
            app.RotateLeftButton.Enable = 'off';
            app.RotateLeftButton.Text = 'Rotate Left';
            app.RotateRightButton = uibutton(app.TransformationPanel, 'push');
            app.RotateRightButton.ButtonPushedFcn = createCallbackFcn(app, @RotateRightButtonPushed, true);
            app.RotateRightButton.Enable = 'off';
            app.RotateRightButton.Text = 'Rotate Right';
            app.Panel_4 = uipanel(app.TransformationPanel);
            app.RotateButton = uibutton(app.Panel_4, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Enable = 'off';
            app.RotateButton.Text = 'Rotate';
            app.EditField_10 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_10ValueChanged, true);
            app.EditField_10.Enable = 'off';
            app.INputImagePanel = uipanel(app.UIFigure);
            app.INputImagePanel.Title = 'INput Image';
            app.UIAxes = uiaxes(app.INputImagePanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.HistogramButton = uibutton(app.INputImagePanel, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Enable = 'off';
            app.HistogramButton.Text = 'Histogram';
            app.LOADIMAGEButton = uibutton(app.INputImagePanel, 'push');
            app.LOADIMAGEButton.ButtonPushedFcn = createCallbackFcn(app, @LOADIMAGEButtonPushed, true);
            app.LOADIMAGEButton.Text = 'LOAD IMAGE';
            app.OutputImagePanel = uipanel(app.UIFigure);
            app.OutputImagePanel.Title = 'Output Image';
            app.UIAxes_2 = uiaxes(app.OutputImagePanel);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.HistogramButton_2 = uibutton(app.OutputImagePanel, 'push');
            app.HistogramButton_2.ButtonPushedFcn = createCallbackFcn(app, @HistogramButton_2Pushed, true);
            app.HistogramButton_2.Enable = 'off';
            app.HistogramButton_2.Text = 'Histogram';
            app.EXPORTIMAGEButton = uibutton(app.OutputImagePanel, 'push');
            app.EXPORTIMAGEButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTIMAGEButtonPushed, true);
            app.EXPORTIMAGEButton.Enable = 'off';
            app.EXPORTIMAGEButton.Text = 'EXPORT IMAGE';
            app.PHOTOLABLabel = uilabel(app.UIFigure);
            app.PHOTOLABLabel.Text = 'PHOTO LAB';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Enable = 'off';
            app.RESETButton.Text = 'RESET';
            app.HistogramPanel = uipanel(app.UIFigure);
            app.HistogramPanel.Title = 'Histogram';
            app.UIAxes_3 = uiaxes(app.HistogramPanel);
            title(app.UIAxes_3, 'Histogram of INput')
            xlabel(app.UIAxes_3, 'No. of Gray Levels')
            ylabel(app.UIAxes_3, 'Values')
            app.UIAxes_3.XLim = [0 255];
            app.UIAxes_3.XTick = [0 255];
            app.UIAxes_3.XTickLabel = {'0'; '255'};
            app.UIAxes_3.ButtonDownFcn = createCallbackFcn(app, @UIAxes_3ButtonDown, true);
            app.UIAxes_4 = uiaxes(app.HistogramPanel);
            title(app.UIAxes_4, 'Histogram of Output')
            xlabel(app.UIAxes_4, 'No. of Gray Levels')
            ylabel(app.UIAxes_4, 'Values')
            app.UIAxes_4.XLim = [0 255];
            app.UIAxes_4.XTick = [0 255];
            app.UIAxes_4.XTickLabel = {'0'; '255'};
            app.StegnographyPanel = uipanel(app.UIFigure);
            app.StegnographyPanel.Title = 'Stegnography';
            app.SelectImagetoHideButton = uibutton(app.StegnographyPanel, 'push');
            app.SelectImagetoHideButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImagetoHideButtonPushed, true);
            app.SelectImagetoHideButton.Enable = 'off';
            app.SelectImagetoHideButton.Text = 'Select Image to Hide';
            app.SelectImagetohideInButton = uibutton(app.StegnographyPanel, 'push');
            app.SelectImagetohideInButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImagetohideInButtonPushed, true);
            app.SelectImagetohideInButton.Enable = 'off';
            app.SelectImagetohideInButton.Text = 'Select Image to hide In';
            app.EncodeButton = uibutton(app.StegnographyPanel, 'push');
            app.EncodeButton.ButtonPushedFcn = createCallbackFcn(app, @EncodeButtonPushed, true);
            app.EncodeButton.Enable = 'off';
            app.EncodeButton.Text = 'Encode';
            app.DecodeButton = uibutton(app.StegnographyPanel, 'push');
            app.DecodeButton.ButtonPushedFcn = createCallbackFcn(app, @DecodeButtonPushed, true);
            app.DecodeButton.Enable = 'off';
            app.DecodeButton.Text = 'Decode';
            app.Show8BitPlanesButton = uibutton(app.StegnographyPanel, 'push');
            app.Show8BitPlanesButton.ButtonPushedFcn = createCallbackFcn(app, @Show8BitPlanesButtonPushed, true);
            app.Show8BitPlanesButton.Enable = 'off';
            app.Show8BitPlanesButton.Text = 'Show 8 Bit Planes';
            app.MagnifyOutput_ImageButton = uibutton(app.UIFigure, 'push');
            app.MagnifyOutput_ImageButton.ButtonPushedFcn = createCallbackFcn(app, @MagnifyOutput_ImageButtonPushed, true);
            app.MagnifyOutput_ImageButton.Enable = 'off';
            app.MagnifyOutput_ImageButton.Text = 'Magnify Output_Image';
            app.ColoringPanel = uipanel(app.UIFigure);
            app.ColoringPanel.Title = 'Coloring';
            app.SudoColoringPanel = uipanel(app.ColoringPanel);
            app.SudoColoringPanel.Title = 'Sudo Coloring';
            app.IntensitySlicingButton = uibutton(app.SudoColoringPanel, 'push');
            app.IntensitySlicingButton.ButtonPushedFcn = createCallbackFcn(app, @IntensitySlicingButtonPushed, true);
            app.IntensitySlicingButton.Enable = 'off';
            app.IntensitySlicingButton.Text = 'Intensity Slicing';
            app.LUTButton = uibutton(app.SudoColoringPanel, 'push');
            app.LUTButton.ButtonPushedFcn = createCallbackFcn(app, @LUTButtonPushed, true);
            app.LUTButton.Enable = 'off';
            app.LUTButton.Text = 'LUT';
            app.DisplayTableButton = uibutton(app.SudoColoringPanel, 'push');
            app.DisplayTableButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayTableButtonPushed, true);
            app.DisplayTableButton.Enable = 'off';
            app.DisplayTableButton.Text = 'Display Table';
            app.ConversionsPanel = uipanel(app.ColoringPanel);
            app.ConversionsPanel.Title = 'Conversions';
            app.NTSCButton = uibutton(app.ConversionsPanel, 'push');
            app.NTSCButton.ButtonPushedFcn = createCallbackFcn(app, @NTSCButtonPushed, true);
            app.NTSCButton.Enable = 'off';
            app.NTSCButton.Text = 'NTSC';
            app.YCbCrButton = uibutton(app.ConversionsPanel, 'push');
            app.YCbCrButton.ButtonPushedFcn = createCallbackFcn(app, @YCbCrButtonPushed, true);
            app.YCbCrButton.Enable = 'off';
            app.YCbCrButton.Text = 'YCbCr';
            app.HSVButton = uibutton(app.ConversionsPanel, 'push');
            app.HSVButton.ButtonPushedFcn = createCallbackFcn(app, @HSVButtonPushed, true);
            app.HSVButton.Enable = 'off';
            app.HSVButton.Text = 'HSV';
            app.CMYButton = uibutton(app.ConversionsPanel, 'push');
            app.CMYButton.ButtonPushedFcn = createCallbackFcn(app, @CMYButtonPushed, true);
            app.CMYButton.Enable = 'off';
            app.CMYButton.Text = 'CMY';
            app.ShowAllConversionsButton = uibutton(app.ConversionsPanel, 'push');
            app.ShowAllConversionsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAllConversionsButtonPushed, true);
            app.ShowAllConversionsButton.Enable = 'off';
            app.ShowAllConversionsButton.Text = 'Show All Conversions';
            app.InvertColorButton = uibutton(app.ConversionsPanel, 'push');
            app.InvertColorButton.ButtonPushedFcn = createCallbackFcn(app, @InvertColorButtonPushed, true);
            app.InvertColorButton.Enable = 'off';
            app.InvertColorButton.Text = 'Invert Color';
            app.GrayScaleButton = uibutton(app.ConversionsPanel, 'push');
            app.GrayScaleButton.ButtonPushedFcn = createCallbackFcn(app, @GrayScaleButtonPushed, true);
            app.GrayScaleButton.Enable = 'off';
            app.GrayScaleButton.Text = 'GrayScale';
            app.SlidersPanel = uipanel(app.UIFigure);
            app.SlidersPanel.Title = 'Sliders';
            app.Panel_5 = uipanel(app.SlidersPanel);
            app.Slider = uislider(app.Panel_5);
            app.Slider.Limits = [-100 100];
            app.Slider.MajorTicks = [-100 -50 0 50 100];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [-100 -95 -90 -85 -80 -75 -70 -65 -60 -55 -50 -45 -40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.Slider.Enable = 'off';
            app.BrightnessLabel = uilabel(app.Panel_5);
            app.BrightnessLabel.Enable = 'off';
            app.BrightnessLabel.Text = 'Brightness';
            app.saveButton = uibutton(app.Panel_5, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Enable = 'off';
            app.saveButton.Text = 'save';
            app.Panel_6 = uipanel(app.SlidersPanel);
            app.Slider_7 = uislider(app.Panel_6);
            app.Slider_7.Limits = [-1 1];
            app.Slider_7.MajorTicks = [-1 -0.5 0 0.5 1];
            app.Slider_7.ValueChangingFcn = createCallbackFcn(app, @Slider_7ValueChanging, true);
            app.Slider_7.MinorTicks = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.Slider_7.Enable = 'off';
            app.saveButton_2 = uibutton(app.Panel_6, 'push');
            app.saveButton_2.ButtonPushedFcn = createCallbackFcn(app, @saveButton_2Pushed, true);
            app.saveButton_2.Enable = 'off';
            app.saveButton_2.Text = 'save';
            app.HueLabel_2 = uilabel(app.Panel_6);
            app.HueLabel_2.Enable = 'off';
            app.HueLabel_2.Text = 'Hue';
            app.Panel_8 = uipanel(app.SlidersPanel);
            app.Slider_9 = uislider(app.Panel_8);
            app.Slider_9.Limits = [-100 100];
            app.Slider_9.MajorTicks = [-100 -50 0 50 100];
            app.Slider_9.ValueChangingFcn = createCallbackFcn(app, @Slider_9ValueChanging, true);
            app.Slider_9.MinorTicks = [-100 -95 -90 -85 -80 -75 -70 -65 -60 -55 -50 -45 -40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.Slider_9.Enable = 'off';
            app.saveButton_4 = uibutton(app.Panel_8, 'push');
            app.saveButton_4.ButtonPushedFcn = createCallbackFcn(app, @saveButton_4Pushed, true);
            app.saveButton_4.Enable = 'off';
            app.saveButton_4.Text = 'save';
            app.RedComponentLabel = uilabel(app.Panel_8);
            app.RedComponentLabel.Enable = 'off';
            app.RedComponentLabel.Text = 'Red Component';
            app.Panel_9 = uipanel(app.SlidersPanel);
            app.Slider_10 = uislider(app.Panel_9);
            app.Slider_10.Limits = [-100 100];
            app.Slider_10.MajorTicks = [-100 -50 0 50 100];
            app.Slider_10.ValueChangingFcn = createCallbackFcn(app, @Slider_10ValueChanging, true);
            app.Slider_10.MinorTicks = [-100 -95 -90 -85 -80 -75 -70 -65 -60 -55 -50 -45 -40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.Slider_10.Enable = 'off';
            app.saveButton_5 = uibutton(app.Panel_9, 'push');
            app.saveButton_5.ButtonPushedFcn = createCallbackFcn(app, @saveButton_5Pushed, true);
            app.saveButton_5.Enable = 'off';
            app.saveButton_5.Text = 'save';
            app.GreenComponentLabel = uilabel(app.Panel_9);
            app.GreenComponentLabel.Enable = 'off';
            app.GreenComponentLabel.Text = 'Green Component';
            app.Panel_10 = uipanel(app.SlidersPanel);
            app.Slider_11 = uislider(app.Panel_10);
            app.Slider_11.Limits = [-100 100];
            app.Slider_11.MajorTicks = [-100 -50 0 50 100];
            app.Slider_11.ValueChangingFcn = createCallbackFcn(app, @Slider_11ValueChanging, true);
            app.Slider_11.MinorTicks = [-100 -95 -90 -85 -80 -75 -70 -65 -60 -55 -50 -45 -40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.Slider_11.Enable = 'off';
            app.saveButton_6 = uibutton(app.Panel_10, 'push');
            app.saveButton_6.ButtonPushedFcn = createCallbackFcn(app, @saveButton_6Pushed, true);
            app.saveButton_6.Enable = 'off';
            app.saveButton_6.Text = 'save';
            app.BlueComponentLabel = uilabel(app.Panel_10);
            app.BlueComponentLabel.Enable = 'off';
            app.BlueComponentLabel.Text = 'Blue Component';
            app.Panel_11 = uipanel(app.SlidersPanel);
            app.saveButton_7 = uibutton(app.Panel_11, 'push');
            app.saveButton_7.ButtonPushedFcn = createCallbackFcn(app, @saveButton_7Pushed, true);
            app.saveButton_7.Enable = 'off';
            app.saveButton_7.Text = 'save';
            app.IntensityLabel = uilabel(app.Panel_11);
            app.IntensityLabel.Enable = 'off';
            app.IntensityLabel.Text = 'Intensity';
            app.Slider_13 = uislider(app.Panel_11);
            app.Slider_13.Limits = [-1 1];
            app.Slider_13.MajorTicks = [-1 -0.5 0 0.5 1];
            app.Slider_13.ValueChangingFcn = createCallbackFcn(app, @Slider_13ValueChanging, true);
            app.Slider_13.MinorTicks = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.Slider_13.Enable = 'off';
            app.Panel_7 = uipanel(app.SlidersPanel);
            app.saveButton_3 = uibutton(app.Panel_7, 'push');
            app.saveButton_3.ButtonPushedFcn = createCallbackFcn(app, @saveButton_3Pushed, true);
            app.saveButton_3.Enable = 'off';
            app.saveButton_3.Text = 'save';
            app.SaturationLabel = uilabel(app.Panel_7);
            app.SaturationLabel.Enable = 'off';
            app.SaturationLabel.Text = 'Saturation';
            app.Slider_12 = uislider(app.Panel_7);
            app.Slider_12.Limits = [-1 1];
            app.Slider_12.MajorTicks = [-1 -0.5 0 0.5 1];
            app.Slider_12.ValueChangingFcn = createCallbackFcn(app, @Slider_12ValueChanging, true);
            app.Slider_12.MinorTicks = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.Slider_12.Enable = 'off';
            app.ImageEnhancementPanel = uipanel(app.UIFigure);
            app.ImageEnhancementPanel.Title = 'Image Enhancement';
            app.AutoContrastButton = uibutton(app.ImageEnhancementPanel, 'push');
            app.AutoContrastButton.ButtonPushedFcn = createCallbackFcn(app, @AutoContrastButtonPushed, true);
            app.AutoContrastButton.Enable = 'off';
            app.AutoContrastButton.Text = 'Auto Contrast';
            app.DehazeButton = uibutton(app.ImageEnhancementPanel, 'push');
            app.DehazeButton.ButtonPushedFcn = createCallbackFcn(app, @DehazeButtonPushed, true);
            app.DehazeButton.Enable = 'off';
            app.DehazeButton.Text = 'Dehaze';
            app.LaplacianButton = uibutton(app.ImageEnhancementPanel, 'push');
            app.LaplacianButton.ButtonPushedFcn = createCallbackFcn(app, @LaplacianButtonPushed, true);
            app.LaplacianButton.Enable = 'off';
            app.LaplacianButton.Text = 'Laplacian ';
            app.CompositeButton = uibutton(app.ImageEnhancementPanel, 'push');
            app.CompositeButton.ButtonPushedFcn = createCallbackFcn(app, @CompositeButtonPushed, true);
            app.CompositeButton.Enable = 'off';
            app.CompositeButton.Text = 'Composite';
            app.HistogramEQButton = uibutton(app.ImageEnhancementPanel, 'push');
            app.HistogramEQButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramEQButtonPushed, true);
            app.HistogramEQButton.Enable = 'off';
            app.HistogramEQButton.Text = 'Histogram EQ';
            app.AdaptiveHistEQButton = uibutton(app.ImageEnhancementPanel, 'push');
            app.AdaptiveHistEQButton.ButtonPushedFcn = createCallbackFcn(app, @AdaptiveHistEQButtonPushed, true);
            app.AdaptiveHistEQButton.Enable = 'off';
            app.AdaptiveHistEQButton.Text = 'Adaptive Hist EQ';
            app.AdaptiveLocalNoiseReductionButton = uibutton(app.ImageEnhancementPanel, 'push');
            app.AdaptiveLocalNoiseReductionButton.ButtonPushedFcn = createCallbackFcn(app, @AdaptiveLocalNoiseReductionButtonPushed, true);
            app.AdaptiveLocalNoiseReductionButton.Enable = 'off';
            app.AdaptiveLocalNoiseReductionButton.Text = 'Adaptive Local Noise Reduction';
            app.RGBPlanesButton = uibutton(app.UIFigure, 'push');
            app.RGBPlanesButton.ButtonPushedFcn = createCallbackFcn(app, @RGBPlanesButtonPushed, true);
            app.RGBPlanesButton.Enable = 'off';
            app.RGBPlanesButton.Text = 'RGB Planes';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PHOTO_LAB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end